classdef HoloApp_Lito < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        TargetfilenameEditField        matlab.ui.control.EditField
        TargetfilenameEditFieldLabel   matlab.ui.control.Label
        SavetargetimageButton          matlab.ui.control.Button
        SaveOutputImageButton          matlab.ui.control.Button
        OutputfilenameEditField        matlab.ui.control.EditField
        OutputfilenameEditFieldLabel   matlab.ui.control.Label
        TabGroup                       matlab.ui.container.TabGroup
        Step1Tab                       matlab.ui.container.Tab
        ScaleTargetEditField           matlab.ui.control.NumericEditField
        ScaleTargetEditFieldLabel      matlab.ui.control.Label
        ImageSelectionListBox          matlab.ui.control.ListBox
        ImageSelectionListBoxLabel     matlab.ui.control.Label
        Step2Tab                       matlab.ui.container.Tab
        ListBox                        matlab.ui.control.ListBox
        OraddalreadygeneratedphasemapLabel  matlab.ui.control.Label
        ApplyGSroutineButton           matlab.ui.control.Button
        BeamdiametermmEditField        matlab.ui.control.NumericEditField
        BeamdiametermmEditFieldLabel   matlab.ui.control.Label
        iterationsforGSEditField       matlab.ui.control.NumericEditField
        iterationsforGSEditFieldLabel  matlab.ui.control.Label
        GerchbergSaxtonRoutineLabel    matlab.ui.control.Label
        Step3Tab                       matlab.ui.container.Tab
        ApplytoSLMButton               matlab.ui.control.Button
        SLMconnectedLamp               matlab.ui.control.Lamp
        SLMconnectedLampLabel          matlab.ui.control.Label
        ExtramovingfocalpointTab       matlab.ui.container.Tab
        TranslatestonmLabel            matlab.ui.control.Label
        EditField_3                    matlab.ui.control.NumericEditField
        Centre961577Label              matlab.ui.control.Label
        EditField_2                    matlab.ui.control.NumericEditField
        EditField                      matlab.ui.control.NumericEditField
        ApplychangesButton             matlab.ui.control.Button
        DiametermmEditField            matlab.ui.control.NumericEditField
        DiametermmEditFieldLabel       matlab.ui.control.Label
        VerticalposSlider              matlab.ui.control.Slider
        VerticalposSliderLabel         matlab.ui.control.Label
        HorizontalposSlider            matlab.ui.control.Slider
        HorizontalposSliderLabel       matlab.ui.control.Label
        StartwithafocalpointButton     matlab.ui.control.Button
        EasyHoloAppLabel               matlab.ui.control.Label
        UIAxes3                        matlab.ui.control.UIAxes
        UIAxes2                        matlab.ui.control.UIAxes
        UIAxes                         matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        outDir % Output directory: where phase images can be stored if the User presses the "Save Output" button
        tgDir  % Target directory: where target images are stored
        filesList % Available target files
        outFilesList % List of available phase maps
        target % Current target image of SLM, shown in UIAxes (left panel)
        phase % Phase image, shown in UIAxes2 (right panel)
        n_iter % Number of GS routine iterations
        SLM_dims % Dimensions of SLM
        SLM_pitch
        beam_diameter % Diameter of the incident beam to the SLM (in m)
        out_filename % Output file name: name of the image file if the User presses the "Save Output" button
        tgt_filename % Name of the file used for the target - it will change if the image is moved or scaled
        tgt_filename_original % original filename of the target
        SLM_connected % Logical variable, indicated whether a correct connection has been established with the SLM
        selected_img % If the User has chosen an image from the tgt direectory, this variable corresponds to this image
        beam_gauss % Amplitude distribution of light hitting the SLM

        % SLM connectivity variables
        num_boards_found 
        wait_For_Trigger
        flip_immediate
        timeout_ms
        SDK
        OutputPulseImageFlip
        OutputPulseImageRefresh

        % Focal point variables
        fp_diam % Diameter of the target circle (on the SLM surface) in m
        centre_SLM % Centre coordinates of the SLM
        offset_fp % Offset from centre at which the circle is found
        fp_diam_pixels % Diameter of target circle in # pixels
    end
    
    
    methods (Access = private)
        
        function target = resize_to_SLM(app, selected_img)
            dim_img = size(selected_img);

            padY=0;
            padX=0;

            if ~all(dim_img>app.SLM_dims)
                log_arr = dim_img<=app.SLM_dims;

                if log_arr(1)
                    padY = app.SLM_dims(1)-dim_img(1);
                else
                    disp('Image will be cut in y axis');
                end

                if log_arr(2)
                    padX = app.SLM_dims(2)-dim_img(2);
                else
                    disp('Image will be cut in x axis');
                end
            end
            

            target = padarray(selected_img,round([padY padX]./2),0,'both');

            win1 = centerCropWindow2d(size(target),app.SLM_dims);
            target = imcrop(target,win1);
            % target = imresize(target,app.SLM_dims);
        end
        
        function n = get_num_decimal_pts(~, decimal)
                n=0;
                while (floor(decimal*10^n)~=decimal*10^n)
                    n=n+1;
                end
            
        end
    end
   

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.tgDir = '..\img\tgt\';
            app.filesList = dir(app.tgDir);
            app.ImageSelectionListBox.Items = {app.filesList.name}';
            app.n_iter= app.iterationsforGSEditField.Value;
            app.SLM_dims = [1152,1920];
            app.SLM_pitch = 9.2e-6;
            app.beam_diameter = app.BeamdiametermmEditField.Value;
            app.beam_diameter = app.beam_diameter*1e-3;
            app.outDir = '..\img\out\';
            app.outFilesList = dir(app.outDir);
            app.ListBox.Items = {app.outFilesList.name}';
            app.out_filename = app.OutputfilenameEditField.Value;
            app.SLM_connected = false;
            app.target = zeros(app.SLM_dims);

            %% Configuring figures           
            axis(app.UIAxes, 'tight')
           
            axis(app.UIAxes2, 'tight')
            axis(app.UIAxes3, 'tight')
            colormap(app.UIAxes2, 'gray');
            colormap(app.UIAxes3, 'gray');
            colormap(app.UIAxes, 'gray');
            app.UIAxes.PlotBoxAspectRatio = [1920,1152,1];
            app.UIAxes2.PlotBoxAspectRatio = [1920,1152,1];

            %% moving focal point variables
            app.fp_diam = app.DiametermmEditField.Value*1e-3;
            app.fp_diam_pixels = round(app.fp_diam/app.SLM_pitch);
            app.offset_fp = zeros(1,2);
            app.offset_fp(1)= app.VerticalposSlider.Value;
            app.offset_fp(2)= app.HorizontalposSlider.Value;
            app.centre_SLM = [961,577];
            app.EditField_3.Value = app.fp_diam / 1064e-9 / 100e-3; %not sure :(

            %% SLM connection attempt
            bit_depth = 12; %bit depth = 8 for small 512, 12 for 1920
            app.num_boards_found = libpointer('uint32Ptr', 0);
            constructed_okay = libpointer('int32Ptr', 0);
            is_nematic_type = 1;
            RAM_write_enable = 1;
            use_GPU = 0;
            max_transients = 10;
            app.wait_For_Trigger = 0; % This feature is user-settable; use 1 for 'on' or 0 for 'off'
            
            app.flip_immediate = 0; % Only supported on the 1024
            app.timeout_ms = 5000;
            app.SDK = struct();
            
            app.OutputPulseImageFlip = 0;
            app.OutputPulseImageRefresh = 0;
            reg_lut = libpointer('string');
            if ~libisloaded('Blink_C_wrapper')
               loadlibrary('../lib/Blink_C_wrapper.dll', '../lib/Blink_C_wrapper.h');
            end
            if ~libisloaded('ImageGen')
                loadlibrary('../lib/ImageGen.dll', '../lib/ImageGen.h');
            end
            disp('Libraries loaded.')
            disp('Trying to connect')
            
            disp('Trying to create SDK.')
            calllib('Blink_C_wrapper', 'Create_SDK', bit_depth, app.num_boards_found, constructed_okay, is_nematic_type, RAM_write_enable, use_GPU, max_transients, reg_lut);
            if constructed_okay ~= 1
                disp('SDK NOT constructed ok.')
                
                %% Mirar en MPUS PC
                % errMsg = calllib('Blink_C_wrapper', 'Get_last_error_message');
                % disp(errMsg)
            end
            
            disp(['Num boards: ', num2str(app.num_boards_found.val)])
            
            if app.num_boards_found.val > 0
                app.SLM_connected = true;
                disp('Blink SDK was successfully constructed');
                app.SLMconnectedLamp.Color = [112, 209, 109]./255;
            end

        end

        % Value changed function: ImageSelectionListBox
        function ImageSelectionListBoxValueChanged(app, event)
            value = app.ImageSelectionListBox.Value;
            app.selected_img = imread(strcat(app.tgDir,value)); %Get target
            app.selected_img = double(mean(app.selected_img,3));
            
            % Save filename for later
            app.tgt_filename_original = extractBefore(value, '.');
            app.tgt_filename = app.tgt_filename_original;
            app.TargetfilenameEditField.Value = app.tgt_filename;


            % Resize image if needed
            app.target = resize_to_SLM(app,app.selected_img);
            
            %Start at scale = 1
            app.ScaleTargetEditField.Value = 1;

            % imshow(app.target,'Parent', app.UIAxes);

            imagesc(app.UIAxes,app.target);
            

        end

        % Button pushed function: ApplyGSroutineButton
        function ApplyGSroutineButtonPushed(app, event)
            
            slm_beam_rad = round(app.beam_diameter/(2*app.SLM_pitch)); 
            [slmX, slmY] = meshgrid(-round(app.SLM_dims(2)/2):round(app.SLM_dims(2)/2)-1,-round(app.SLM_dims(1)/2):round(app.SLM_dims(1)/2)-1); 
            slmR = sqrt(slmX.^2 + slmY.^2);
            app.beam_gauss = exp(-2*slmR.^2/slm_beam_rad^2);

            % Do GS routine
            app.phase = GS_routine(app.target,app.beam_gauss,app.n_iter);
            
            % Show result
            imagesc(app.UIAxes2,app.phase);

           est_result = fft2(app.beam_gauss.*exp(1i.*app.phase));
           imagesc(app.UIAxes3,real(est_result));
        end

        % Value changed function: iterationsforGSEditField
        function iterationsforGSEditFieldValueChanged(app, event)
            app.n_iter = app.iterationsforGSEditField.Value;
            
        end

        % Value changed function: BeamdiametermmEditField
        function BeamdiametermmEditFieldValueChanged(app, event)
            app.beam_diameter = app.BeamdiametermmEditField.Value .* 1e-3;
            
        end

        % Button pushed function: SaveOutputImageButton
        function SaveOutputImageButtonPushed(app, event)
            img_to_save = uint8((app.phase).*255./(2*pi));
            imwrite(img_to_save,[app.outDir,app.out_filename,'.bmp'])
        end

        % Value changed function: OutputfilenameEditField
        function OutputfilenameEditFieldValueChanged(app, event)
            app.out_filename = app.OutputfilenameEditField.Value;
            
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            if app.SLM_connected
                calllib('Blink_C_wrapper', 'Delete_SDK');
                disp('Deleting SDK and closing app')
            else
                disp('Closing app. No SDK present.')
            end
            delete(app)
            
        end

        % Button pushed function: ApplytoSLMButton
        function ApplytoSLMButtonPushed(app, event)
            if app.num_boards_found.val > 0
                disp(['Found ',num2str(app.num_boards_found),' SLM controller(s)'])
                board_number =1;
                disp('Getting SLM information.')
                height = calllib('Blink_C_wrapper', 'Get_image_height', board_number);
                width = calllib('Blink_C_wrapper', 'Get_image_width', board_number);
                depth = calllib('Blink_C_wrapper', 'Get_image_depth', board_number); %bits per pixel
                Bytes = depth/8;
                ImageOne = libpointer('uint8Ptr', zeros(width*height*Bytes,1)); 
                SLM_phase = uint8((app.phase).*255./(2*pi));
                ImageOne.value = flip(transpose(SLM_phase));

                %% Send info to SLM
                disp('Loading LUT.')
                calllib('Blink_C_wrapper', 'Load_LUT_file', board_number, 'C:\\Program Files\\Meadowlark Optics\\Blink OverDrive Plus\\LUT Files\\slm6254_at532_PCIe.LUT');
                calllib('Blink_C_wrapper', 'Write_image', board_number, ImageOne, width*height*Bytes, app.wait_For_Trigger, app.flip_immediate, app.OutputPulseImageFlip, app.OutputPulseImageRefresh, app.timeout_ms);
                calllib('Blink_C_wrapper', 'ImageWriteComplete', board_number, app.timeout_ms);  
                disp('You should see something on your focal plane now :)')
            else
                disp('Not connected to SLM!')
            end
        end

        % Button pushed function: StartwithafocalpointButton
        function StartwithafocalpointButtonPushed(app, event)
            blank_img = zeros(app.SLM_dims);
            % imagesc(app.UIAxes, app.target);
            h=insertShape(blank_img,'filled-circle',[app.centre_SLM+app.offset_fp round(app.fp_diam_pixels/2)],'ShapeColor',[1,1,1],'Opacity',1);
            app.target = mean(h,3);
            imshow(app.target,'Parent',app.UIAxes);
            
        end

        % Value changed function: HorizontalposSlider
        function HorizontalposSliderValueChanged(app, event)
            value = app.HorizontalposSlider.Value;
            app.offset_fp(1)=round(value);
            app.EditField.Value = app.offset_fp(1);
        end

        % Value changed function: EditField
        function EditFieldValueChanged(app, event)
            value = app.EditField.Value;
            app.offset_fp(1) = value;
            app.HorizontalposSlider.Value = value;
        end

        % Button pushed function: ApplychangesButton
        function ApplychangesButtonPushed(app, event)
            h=insertShape(zeros(app.SLM_dims),'filled-circle',[app.centre_SLM+app.offset_fp round(app.fp_diam_pixels/2)],'ShapeColor',[1,1,1],'Opacity',1);
            app.target = mean(h,3);
            imshow(app.target,'Parent',app.UIAxes);
        end

        % Value changed function: VerticalposSlider
        function VerticalposSliderValueChanged(app, event)
            value = app.VerticalposSlider.Value;
            app.offset_fp(2)=round(-value);
            app.EditField_2.Value = round(value);
        end

        % Value changed function: EditField_2
        function EditField_2ValueChanged(app, event)
            value = app.EditField_2.Value;
            app.offset_fp(2)=-value;
            app.VerticalposSlider.Value = value;
        end

        % Value changed function: DiametermmEditField
        function DiametermmEditFieldValueChanged(app, event)
            value = app.DiametermmEditField.Value;
            app.fp_diam = value*1e-3;
            app.fp_diam_pixels = round(app.fp_diam/app.SLM_pitch);
            actual_size = app.fp_diam*100e-3*1064e-9;
            app.EditField_3.Value = actual_size*1e9;
        end

        % Value changed function: ScaleTargetEditField
        function ScaleTargetEditFieldValueChanged(app, event)
            value = app.ScaleTargetEditField.Value;
            app.selected_img = imresize(app.selected_img,value);
            app.target = resize_to_SLM(app,app.selected_img);
            
            new_scale = value;

            if contains(app.tgt_filename, '_scaled_')
                scale_before = extractAfter(app.tgt_filename, '_scaled_');
                new_scale = value*str2double(scale_before);
            end

            if value ~= 1 
                floored_scale = floor(new_scale);
                if floored_scale==new_scale
                    app.tgt_filename = strcat(app.tgt_filename_original,'_scaled_',num2str(new_scale));
                else
                    decimal = new_scale-floored_scale;
                    power = get_num_decimal_pts(app,decimal);
                    app.tgt_filename = strcat(app.tgt_filename_original,'_scaled_',num2str(floored_scale),'dot',num2str(decimal*(10^power)));
                end
            end
            app.TargetfilenameEditField.Value = app.tgt_filename;

            imshow(app.target, 'Parent',app.UIAxes)
        end

        % Button pushed function: SavetargetimageButton
        function SavetargetimageButtonPushed(app, event)
            if strcmp(app.tgt_filename,app.tgt_filename_original)
                disp('Image will NOT be saved! The image has not been changed yet.')
            else
                imwrite(app.target,[app.tgDir,app.tgt_filename,'.bmp'])
                
            end
        end

        % Clicked callback: ListBox
        function ListBoxClicked(app, event)
            
        end

        % Value changed function: ListBox
        function ListBoxValueChanged(app, event)
            value = app.ListBox.Value;
            app.phase = imread(strcat(app.outDir,value)); %Get phase map
            app.phase = double(mean(app.phase,3));

            % Resize if needed
            app.phase = resize_to_SLM(app,app.phase);
            app.phase = app.phase.*(2*pi)./255;
            %% Get target

            % Get the amplitude map of incident light on the SLM
            slm_beam_rad = round(app.beam_diameter/(2*app.SLM_pitch)); 
            [slmX, slmY] = meshgrid(-round(app.SLM_dims(2)/2):round(app.SLM_dims(2)/2)-1,-round(app.SLM_dims(1)/2):round(app.SLM_dims(1)/2)-1); 
            slmR = sqrt(slmX.^2 + slmY.^2);
            app.beam_gauss = exp(-2*slmR.^2/slm_beam_rad^2);

            %% Need to find conversion to phase info
            app.target=real(ifft2(app.beam_gauss.*exp(app.phase.*1i)));
            
            % Show in figures
            imagesc(app.UIAxes2,app.phase);
            imagesc(app.UIAxes,app.target);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Target Image')
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'Gerchberg-Saxton Routine Output')
            app.UIAxes2.XTick = [];
            app.UIAxes2.YTick = [];
            app.UIAxes3 = uiaxes(app.UIFigure);
            title(app.UIAxes3, 'Focal plane estimate')
            zlabel(app.UIAxes3, 'Z')
            app.UIAxes3.XTick = [];
            app.UIAxes3.YTick = [];
            app.EasyHoloAppLabel = uilabel(app.UIFigure);
            app.EasyHoloAppLabel.Text = 'Easy HoloApp';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.Step1Tab = uitab(app.TabGroup);
            app.Step1Tab.Title = 'Step 1';
            app.ImageSelectionListBoxLabel = uilabel(app.Step1Tab);
            app.ImageSelectionListBoxLabel.Text = '1. Image Selection';
            app.ImageSelectionListBox = uilistbox(app.Step1Tab);
            app.ImageSelectionListBox.Items = {'Target_image_1', 'Target_image_2'};
            app.ImageSelectionListBox.ValueChangedFcn = createCallbackFcn(app, @ImageSelectionListBoxValueChanged, true);
            app.ImageSelectionListBox.Value = {};
            app.ScaleTargetEditFieldLabel = uilabel(app.Step1Tab);
            app.ScaleTargetEditFieldLabel.Text = 'Scale Target';
            app.ScaleTargetEditField = uieditfield(app.Step1Tab, 'numeric');
            app.ScaleTargetEditField.ValueChangedFcn = createCallbackFcn(app, @ScaleTargetEditFieldValueChanged, true);
            app.ScaleTargetEditField.Value = 1;
            app.Step2Tab = uitab(app.TabGroup);
            app.Step2Tab.Title = 'Step 2';
            app.GerchbergSaxtonRoutineLabel = uilabel(app.Step2Tab);
            app.GerchbergSaxtonRoutineLabel.Text = '2. Gerchberg-Saxton Routine';
            app.iterationsforGSEditFieldLabel = uilabel(app.Step2Tab);
            app.iterationsforGSEditFieldLabel.Text = '# iterations for GS';
            app.iterationsforGSEditField = uieditfield(app.Step2Tab, 'numeric');
            app.iterationsforGSEditField.ValueChangedFcn = createCallbackFcn(app, @iterationsforGSEditFieldValueChanged, true);
            app.iterationsforGSEditField.Value = 10;
            app.BeamdiametermmEditFieldLabel = uilabel(app.Step2Tab);
            app.BeamdiametermmEditFieldLabel.Text = 'Beam diameter (mm)';
            app.BeamdiametermmEditField = uieditfield(app.Step2Tab, 'numeric');
            app.BeamdiametermmEditField.ValueChangedFcn = createCallbackFcn(app, @BeamdiametermmEditFieldValueChanged, true);
            app.BeamdiametermmEditField.Value = 20;
            app.ApplyGSroutineButton = uibutton(app.Step2Tab, 'push');
            app.ApplyGSroutineButton.ButtonPushedFcn = createCallbackFcn(app, @ApplyGSroutineButtonPushed, true);
            app.ApplyGSroutineButton.Text = 'Apply GS routine';
            app.OraddalreadygeneratedphasemapLabel = uilabel(app.Step2Tab);
            app.OraddalreadygeneratedphasemapLabel.Text = 'Or add already-generated phase map';
            app.ListBox = uilistbox(app.Step2Tab);
            app.ListBox.Items = {'Phase_map_1', 'Phase_map_2', 'Phase_map_3', ''};
            app.ListBox.ValueChangedFcn = createCallbackFcn(app, @ListBoxValueChanged, true);
            app.ListBox.ClickedFcn = createCallbackFcn(app, @ListBoxClicked, true);
            app.ListBox.Value = 'Phase_map_1';
            app.Step3Tab = uitab(app.TabGroup);
            app.Step3Tab.Title = 'Step 3';
            app.SLMconnectedLampLabel = uilabel(app.Step3Tab);
            app.SLMconnectedLampLabel.Text = 'SLM connected?';
            app.SLMconnectedLamp = uilamp(app.Step3Tab);
            app.ApplytoSLMButton = uibutton(app.Step3Tab, 'push');
            app.ApplytoSLMButton.ButtonPushedFcn = createCallbackFcn(app, @ApplytoSLMButtonPushed, true);
            app.ApplytoSLMButton.Text = 'Apply to SLM';
            app.ExtramovingfocalpointTab = uitab(app.TabGroup);
            app.ExtramovingfocalpointTab.Title = 'Extra: moving focal point';
            app.StartwithafocalpointButton = uibutton(app.ExtramovingfocalpointTab, 'push');
            app.StartwithafocalpointButton.ButtonPushedFcn = createCallbackFcn(app, @StartwithafocalpointButtonPushed, true);
            app.StartwithafocalpointButton.Text = 'Start with a focal point';
            app.HorizontalposSliderLabel = uilabel(app.ExtramovingfocalpointTab);
            app.HorizontalposSliderLabel.Text = 'Horizontal pos';
            app.HorizontalposSlider = uislider(app.ExtramovingfocalpointTab);
            app.HorizontalposSlider.Limits = [-960 961];
            app.HorizontalposSlider.ValueChangedFcn = createCallbackFcn(app, @HorizontalposSliderValueChanged, true);
            app.VerticalposSliderLabel = uilabel(app.ExtramovingfocalpointTab);
            app.VerticalposSliderLabel.Text = 'Vertical pos';
            app.VerticalposSlider = uislider(app.ExtramovingfocalpointTab);
            app.VerticalposSlider.Limits = [-576 575];
            app.VerticalposSlider.ValueChangedFcn = createCallbackFcn(app, @VerticalposSliderValueChanged, true);
            app.DiametermmEditFieldLabel = uilabel(app.ExtramovingfocalpointTab);
            app.DiametermmEditFieldLabel.Text = 'Diameter (mm)';
            app.DiametermmEditField = uieditfield(app.ExtramovingfocalpointTab, 'numeric');
            app.DiametermmEditField.ValueChangedFcn = createCallbackFcn(app, @DiametermmEditFieldValueChanged, true);
            app.DiametermmEditField.Value = 1;
            app.ApplychangesButton = uibutton(app.ExtramovingfocalpointTab, 'push');
            app.ApplychangesButton.ButtonPushedFcn = createCallbackFcn(app, @ApplychangesButtonPushed, true);
            app.ApplychangesButton.Text = 'Apply changes';
            app.EditField = uieditfield(app.ExtramovingfocalpointTab, 'numeric');
            app.EditField.Limits = [-960 961];
            app.EditField.ValueDisplayFormat = '%.0f';
            app.EditField.ValueChangedFcn = createCallbackFcn(app, @EditFieldValueChanged, true);
            app.EditField_2 = uieditfield(app.ExtramovingfocalpointTab, 'numeric');
            app.EditField_2.Limits = [-576 575];
            app.EditField_2.ValueDisplayFormat = '%.0f';
            app.EditField_2.ValueChangedFcn = createCallbackFcn(app, @EditField_2ValueChanged, true);
            app.Centre961577Label = uilabel(app.ExtramovingfocalpointTab);
            app.Centre961577Label.Text = 'Centre: 961,577';
            app.EditField_3 = uieditfield(app.ExtramovingfocalpointTab, 'numeric');
            app.EditField_3.Editable = 'off';
            app.TranslatestonmLabel = uilabel(app.ExtramovingfocalpointTab);
            app.TranslatestonmLabel.Text = 'Translates to (nm)';
            app.OutputfilenameEditFieldLabel = uilabel(app.UIFigure);
            app.OutputfilenameEditFieldLabel.Text = 'Output filename';
            app.OutputfilenameEditField = uieditfield(app.UIFigure, 'text');
            app.OutputfilenameEditField.ValueChangedFcn = createCallbackFcn(app, @OutputfilenameEditFieldValueChanged, true);
            app.OutputfilenameEditField.Value = 'test_file';
            app.SaveOutputImageButton = uibutton(app.UIFigure, 'push');
            app.SaveOutputImageButton.ButtonPushedFcn = createCallbackFcn(app, @SaveOutputImageButtonPushed, true);
            app.SaveOutputImageButton.Text = 'Save Output Image';
            app.SavetargetimageButton = uibutton(app.UIFigure, 'push');
            app.SavetargetimageButton.ButtonPushedFcn = createCallbackFcn(app, @SavetargetimageButtonPushed, true);
            app.SavetargetimageButton.Text = 'Save target image';
            app.TargetfilenameEditFieldLabel = uilabel(app.UIFigure);
            app.TargetfilenameEditFieldLabel.Text = 'Target filename';
            app.TargetfilenameEditField = uieditfield(app.UIFigure, 'text');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = HoloApp_Lito

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end